classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SegmentationMaskLabel          matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        MENUPanel                      matlab.ui.container.Panel
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdingAlgorithmDropDown  matlab.ui.control.DropDown
        ThresholdingAlgorithmDropDownLabel  matlab.ui.control.Label
        NumberofobjectsEditField       matlab.ui.control.NumericEditField
        NumberofobjectsEditFieldLabel  matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        ObjectCounterLabel             matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        Img % Loaded image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            img = Utility.loadImage();
            if(Utility.isGrayscale(img))
                img = repmat(img, 1, 1, 3);
            end
            app.Img = img;
            app.Image.ImageSource = img;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            threshold_algorithm = app.ThresholdingAlgorithmDropDown.Value;
            threshold_value = app.ThresholdSlider.Value;
            
            bw = ObjectCounting.getBinaryImg(app.Img, threshold_algorithm, threshold_value);
            mask = ObjectCounting.getSegmentationMask(bw);
            mask = 255 * repmat(uint8(mask), 1, 1, 3);
            app.Image_2.ImageSource = mask;

            num = ObjectCounting.getNumberOfObjects(mask); 
            app.NumberofobjectsEditField.Value = num;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.ObjectCounterLabel = uilabel(app.UIFigure);
            app.ObjectCounterLabel.Text = 'Object Counter';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.NumberofobjectsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofobjectsEditFieldLabel.Text = 'Number of objects';
            app.NumberofobjectsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.ThresholdingAlgorithmDropDownLabel = uilabel(app.MENUPanel);
            app.ThresholdingAlgorithmDropDownLabel.Text = 'Thresholding Algorithm';
            app.ThresholdingAlgorithmDropDown = uidropdown(app.MENUPanel);
            app.ThresholdingAlgorithmDropDown.Items = {'Selected Threshold', 'Otsu', 'Adaptive'};
            app.ThresholdingAlgorithmDropDown.Value = 'Selected Threshold';
            app.ThresholdSlider = uislider(app.MENUPanel);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.Value = 15;
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.SegmentationMaskLabel = uilabel(app.UIFigure);
            app.SegmentationMaskLabel.Text = 'Segmentation Mask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end